classdef serialControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SimulationControls           simulink.ui.control.SimulationControls
        OL_FreqCommandKnob           matlab.ui.control.Knob
        OL_FreqCommandKnobLabel      matlab.ui.control.Label
        OpenLoopFrequencyGauge       matlab.ui.control.SemicircularGauge
        OpenLoopFrequencyGaugeLabel  matlab.ui.control.Label
        TimeScope                    matlab.ui.scope.TimeScope
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function OL_FreqCommandKnobValueChanged(app, event)
            value = app.OL_FreqCommandKnob.Value;
            
        end

        % Callback function: not associated with a component
        function sta(app, event)
            % initialize(simulation)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'If Action Subsystem:1'; 'If Action Subsystem1:1'};
            app.TimeScope.LegendVisible = 'on';
            app.TimeScope.Title = 'If Action Subsystem:1, If Action Subsystem1:1';
            app.TimeScope.YLabel = 'Amplitude';
            app.TimeScope.XLimits = [0 5];
            app.TimeScope.YLimits = [-1 1];
            app.TimeScope.YLimitsMethod = 'manual';
            app.TimeScope.XTimeSpan = 0.2;
            app.OpenLoopFrequencyGaugeLabel = uilabel(app.UIFigure);
            app.OpenLoopFrequencyGaugeLabel.Text = 'OL_Freq';
            app.OpenLoopFrequencyGauge = uigauge(app.UIFigure, 'semicircular');
            app.OL_FreqCommandKnobLabel = uilabel(app.UIFigure);
            app.OL_FreqCommandKnobLabel.Text = 'OL_Freq_Command';
            app.OL_FreqCommandKnob = uiknob(app.UIFigure, 'continuous');
            app.OL_FreqCommandKnob.Value = 1;
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/If Action Subsystem:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/If Action Subsystem1:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/If Action Subsystem2:1'], app.OpenLoopFrequencyGauge, 'Value');
            bind(app.OL_FreqCommandKnob, 'Value', app.Simulation.TunableVariables, ['OL_Freq_Command:' app.Simulation.ModelName]);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = serialControlApp

            % Associate the Simulink Model
            app.Simulation = simulation('serialControl');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end